classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ConfigNNTab                     matlab.ui.container.Tab
        NumoflayersSpinnerLabel         matlab.ui.control.Label
        NumoflayersSpinner              matlab.ui.control.Spinner
        TrainfunctionDropDownLabel      matlab.ui.control.Label
        TrainfunctionDropDown           matlab.ui.control.DropDown
        TrainfunctionDropDown_2Label    matlab.ui.control.Label
        TrainfunctionDropDown_2         matlab.ui.control.DropDown
        AdapationlearningfunctionDropDownLabel  matlab.ui.control.Label
        AdapationlearningfunctionDropDown  matlab.ui.control.DropDown
        PerformancefunctionDropDownLabel  matlab.ui.control.Label
        PerformancefunctionDropDown     matlab.ui.control.DropDown
        StopconditionsPanel             matlab.ui.container.Panel
        epochsEditFieldLabel            matlab.ui.control.Label
        epochsEditField                 matlab.ui.control.EditField
        min_gradEditFieldLabel          matlab.ui.control.Label
        min_gradEditField               matlab.ui.control.EditField
        validationsEditFieldLabel       matlab.ui.control.Label
        validationsEditField            matlab.ui.control.EditField
        timeEditFieldLabel              matlab.ui.control.Label
        timeEditField                   matlab.ui.control.EditField
        AdvanceconfigurationPanel       matlab.ui.container.Panel
        learningrateEditFieldLabel      matlab.ui.control.Label
        learningrateEditField           matlab.ui.control.EditField
        ratioincreaselrEditFieldLabel   matlab.ui.control.Label
        ratioincreaselrEditField        matlab.ui.control.EditField
        momentumEditFieldLabel          matlab.ui.control.Label
        momentumEditField               matlab.ui.control.EditField
        ratiodecreaselrEditFieldLabel   matlab.ui.control.Label
        ratiodecreaselrEditField        matlab.ui.control.EditField
        CreateButton                    matlab.ui.control.Button
        LayerstranferfunctionPanel      matlab.ui.container.Panel
        Layer1DropDown_7Label           matlab.ui.control.Label
        Layer1DropDown_7                matlab.ui.control.DropDown
        Layer2DropDownLabel             matlab.ui.control.Label
        Layer2DropDown                  matlab.ui.control.DropDown
        Layer3DropDownLabel             matlab.ui.control.Label
        Layer3DropDown                  matlab.ui.control.DropDown
        NumberofneuronsLabel            matlab.ui.control.Label
        EditField                       matlab.ui.control.NumericEditField
        EditField_2                     matlab.ui.control.NumericEditField
        EditField_3                     matlab.ui.control.NumericEditField
        TrainnetworkTab                 matlab.ui.container.Tab
        SimulateTab                     matlab.ui.container.Tab
        Label                           matlab.ui.control.Label
        CurrentnetworkEditFieldLabel    matlab.ui.control.Label
        CurrentnetworkEditField         matlab.ui.control.EditField
        LoaddatasetButton               matlab.ui.control.Button
        DatasetEditFieldLabel           matlab.ui.control.Label
        DatasetEditField                matlab.ui.control.EditField
        AvailabledatasetsDropDownLabel  matlab.ui.control.Label
        AvailabledatasetsDropDown       matlab.ui.control.DropDown
        TargetEditFieldLabel            matlab.ui.control.Label
        TargetEditField                 matlab.ui.control.EditField
        TargetsDropDownLabel            matlab.ui.control.Label
        TargetsDropDown                 matlab.ui.control.DropDown
    end


    properties (Access = public)
        pathToXls = '.\DataSet\ClassificaccaoFolhas.xlsx'; % Description
    end
    properties (Access = public)
        imgSetActive % Description
    end
    properties (Access = public)
        dataInput % Description
        end
    properties (Access = public)
        dataTargetBin % Description
    end


    methods (Access = private)

        % Button pushed function: CreateButton
        function CreateButtonPushed(app, event)


        end

        % Button pushed function: LoaddatasetButton
        function LoaddatasetButtonPushed(app, event)
            
            dataSet = app.AvailabledatasetsDropDown.Value;
            targets = app.TargetsDropDown.Value;
            dataTarget = 0;
           
            
            if dataSet == 'Leafs 1'
                app.imgSetActive = loadDataSets('.\DataSet\Folhas_1', app.pathToXls, 1, 'A2:B991');
                app.DatasetEditField.Value = dataSet;
            end
            
            if dataSet == 'Leafs 2'
                app.imgSetActive = loadDataSets('.\DataSet\Folhas_2', app.pathToXls, 1, 'A2:B991');
                app.DatasetEditField.Value = dataSet;
            end
                 
            if dataSet == 'Leafs 3'
               app.imgSetActive = loadDataSets('.\DataSet\Folhas_3', app.pathToXls, 1, 'A2:B991');
               app.DatasetEditField.Value = dataSet;
            end
            
            app.dataInput  = [app.imgSetActive.binData];
            
            if strcmp(targets, 'Species')
                dataTarget = [app.imgSetActive.speciesId];
                app.TargetEditField.Value = targets;
            end
            
            if strcmp(targets, 'SubSpecies')
                dataTarget = [app.imgSetActive.subSpeciesId];
                app.TargetEditField.Value = targets;
            end
            
            app.dataTargetBin = targetToBinary (dataTarget);


        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ConfigNNTab = uitab(app.TabGroup);
            app.ConfigNNTab.Title = 'Config NN';
            app.NumoflayersSpinnerLabel = uilabel(app.ConfigNNTab);
            app.NumoflayersSpinnerLabel.Text = 'Num of  layers';
            app.NumoflayersSpinner = uispinner(app.ConfigNNTab);
            app.NumoflayersSpinner.Limits = [0 3];
            app.TrainfunctionDropDownLabel = uilabel(app.ConfigNNTab);
            app.TrainfunctionDropDownLabel.Text = 'Train function';
            app.TrainfunctionDropDown = uidropdown(app.ConfigNNTab);
            app.TrainfunctionDropDown_2Label = uilabel(app.ConfigNNTab);
            app.TrainfunctionDropDown_2Label.Text = 'Train function';
            app.TrainfunctionDropDown_2 = uidropdown(app.ConfigNNTab);
            app.AdapationlearningfunctionDropDownLabel = uilabel(app.ConfigNNTab);
            app.AdapationlearningfunctionDropDownLabel.Text = 'Adapation learning function';
            app.AdapationlearningfunctionDropDown = uidropdown(app.ConfigNNTab);
            app.PerformancefunctionDropDownLabel = uilabel(app.ConfigNNTab);
            app.PerformancefunctionDropDownLabel.Text = 'Performance function';
            app.PerformancefunctionDropDown = uidropdown(app.ConfigNNTab);
            app.StopconditionsPanel = uipanel(app.ConfigNNTab);
            app.StopconditionsPanel.Title = 'Stop conditions';
            app.epochsEditFieldLabel = uilabel(app.StopconditionsPanel);
            app.epochsEditFieldLabel.Text = 'epochs';
            app.epochsEditField = uieditfield(app.StopconditionsPanel, 'text');
            app.epochsEditField.Value = '5000';
            app.min_gradEditFieldLabel = uilabel(app.StopconditionsPanel);
            app.min_gradEditFieldLabel.Text = 'min_grad';
            app.min_gradEditField = uieditfield(app.StopconditionsPanel, 'text');
            app.min_gradEditField.Value = '1.e-200';
            app.validationsEditFieldLabel = uilabel(app.StopconditionsPanel);
            app.validationsEditFieldLabel.Text = 'validations';
            app.validationsEditField = uieditfield(app.StopconditionsPanel, 'text');
            app.validationsEditField.Value = '1000000';
            app.timeEditFieldLabel = uilabel(app.StopconditionsPanel);
            app.timeEditFieldLabel.Text = 'time';
            app.timeEditField = uieditfield(app.StopconditionsPanel, 'text');
            app.timeEditField.Value = 'inf';
            app.AdvanceconfigurationPanel = uipanel(app.ConfigNNTab);
            app.AdvanceconfigurationPanel.Title = 'Advance configuration';
            app.learningrateEditFieldLabel = uilabel(app.AdvanceconfigurationPanel);
            app.learningrateEditFieldLabel.Text = 'learning rate';
            app.learningrateEditField = uieditfield(app.AdvanceconfigurationPanel, 'text');
            app.learningrateEditField.Value = '0.01';
            app.ratioincreaselrEditFieldLabel = uilabel(app.AdvanceconfigurationPanel);
            app.ratioincreaselrEditFieldLabel.Text = 'ratio increase lr';
            app.ratioincreaselrEditField = uieditfield(app.AdvanceconfigurationPanel, 'text');
            app.ratioincreaselrEditField.Value = '1.05';
            app.momentumEditFieldLabel = uilabel(app.AdvanceconfigurationPanel);
            app.momentumEditFieldLabel.Text = 'momentum';
            app.momentumEditField = uieditfield(app.AdvanceconfigurationPanel, 'text');
            app.momentumEditField.Value = '0.7';
            app.ratiodecreaselrEditFieldLabel = uilabel(app.AdvanceconfigurationPanel);
            app.ratiodecreaselrEditFieldLabel.Text = 'ratio decrease lr';
            app.ratiodecreaselrEditField = uieditfield(app.AdvanceconfigurationPanel, 'text');
            app.ratiodecreaselrEditField.Value = '0.7';
            app.CreateButton = uibutton(app.ConfigNNTab, 'push');
            app.CreateButton.ButtonPushedFcn = createCallbackFcn(app, @CreateButtonPushed, true);
            app.CreateButton.Text = 'Create';
            app.LayerstranferfunctionPanel = uipanel(app.ConfigNNTab);
            app.LayerstranferfunctionPanel.Title = 'Layers tranfer function';
            app.Layer1DropDown_7Label = uilabel(app.LayerstranferfunctionPanel);
            app.Layer1DropDown_7Label.Text = 'Layer  1';
            app.Layer1DropDown_7 = uidropdown(app.LayerstranferfunctionPanel);
            app.Layer2DropDownLabel = uilabel(app.LayerstranferfunctionPanel);
            app.Layer2DropDownLabel.Text = 'Layer  2';
            app.Layer2DropDown = uidropdown(app.LayerstranferfunctionPanel);
            app.Layer3DropDownLabel = uilabel(app.LayerstranferfunctionPanel);
            app.Layer3DropDownLabel.Text = 'Layer  3';
            app.Layer3DropDown = uidropdown(app.LayerstranferfunctionPanel);
            app.NumberofneuronsLabel = uilabel(app.LayerstranferfunctionPanel);
            app.NumberofneuronsLabel.Text = 'Number of neurons';
            app.EditField = uieditfield(app.LayerstranferfunctionPanel, 'numeric');
            app.EditField.Limits = [0 Inf];
            app.EditField.Value = 10;
            app.EditField_2 = uieditfield(app.LayerstranferfunctionPanel, 'numeric');
            app.EditField_2.Limits = [0 Inf];
            app.EditField_2.Value = 10;
            app.EditField_3 = uieditfield(app.LayerstranferfunctionPanel, 'numeric');
            app.EditField_3.Limits = [0 Inf];
            app.EditField_3.Value = 10;
            app.TrainnetworkTab = uitab(app.TabGroup);
            app.TrainnetworkTab.Title = 'Train network';
            app.SimulateTab = uitab(app.TabGroup);
            app.SimulateTab.Title = 'Simulate';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.CurrentnetworkEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentnetworkEditFieldLabel.Text = 'Current network';
            app.CurrentnetworkEditField = uieditfield(app.UIFigure, 'text');
            app.CurrentnetworkEditField.Editable = 'off';
            app.LoaddatasetButton = uibutton(app.UIFigure, 'push');
            app.LoaddatasetButton.ButtonPushedFcn = createCallbackFcn(app, @LoaddatasetButtonPushed, true);
            app.LoaddatasetButton.Text = 'Load data set';
            app.DatasetEditFieldLabel = uilabel(app.UIFigure);
            app.DatasetEditFieldLabel.Text = 'Data set';
            app.DatasetEditField = uieditfield(app.UIFigure, 'text');
            app.DatasetEditField.Editable = 'off';
            app.AvailabledatasetsDropDownLabel = uilabel(app.UIFigure);
            app.AvailabledatasetsDropDownLabel.Text = 'Available data sets';
            app.AvailabledatasetsDropDown = uidropdown(app.UIFigure);
            app.AvailabledatasetsDropDown.Items = {'Leafs 1', 'Leafs 2', 'Leafs 3'};
            app.AvailabledatasetsDropDown.Value = 'Leafs 1';
            app.TargetEditFieldLabel = uilabel(app.UIFigure);
            app.TargetEditFieldLabel.Text = 'Target';
            app.TargetEditField = uieditfield(app.UIFigure, 'text');
            app.TargetEditField.Editable = 'off';
            app.TargetsDropDownLabel = uilabel(app.UIFigure);
            app.TargetsDropDownLabel.Text = 'Targets';
            app.TargetsDropDown = uidropdown(app.UIFigure);
            app.TargetsDropDown.Items = {'Species', 'SubSpecies'};
            app.TargetsDropDown.Value = 'Species';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end